classdef InterfazNewtonR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        NewtonR         matlab.ui.control.Label
        F1              matlab.ui.control.Button
        F2              matlab.ui.control.Button
        F3              matlab.ui.control.Button
        F4              matlab.ui.control.Button
        A00829022Label  matlab.ui.control.Label
        Grafica         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function FejemploButtonPushed(app, event)

        end

        % Button pushed function: F1
        function F1ButtonPushed(app, event)
            syms x
            f = (-1/10)*x^2+3;
            b = -15:0.1:15;
            y = double(subs(f,b));
            xi = 7;
            error = 10^-10;
            fdx = diff(f);
            [r,xi,iter] = NewtonR(f,fdx,xi,error);
            title(app.Grafica,['El valor de X es =' num2str(xi) ', iteraciones = ' num2str(iter)])
            plot(app.Grafica,b,y,'b',xi,r,'*r')
        end

        % Button pushed function: F2
        function F2ButtonPushed(app, event)
            syms x
            f = cos(x)-x^3;
            b = -3:0.1:4;
            y = double(subs(f,b));
            xi = 1;
            error = 10^-10;
            fdx = diff(f);
            [r,xi,iter] = NewtonR(f,fdx,xi,error);
            title(app.Grafica,['El valor de X es =' num2str(xi) ', iteraciones = ' num2str(iter)])
            plot(app.Grafica,b,y,'b',xi,r,'*r')
        end

        % Button pushed function: F3
        function F3ButtonPushed(app, event)
            syms x
            f = (x^3)+x+16;
            b = -6:0.1:6;
            y = double(subs(f,b));
            xi = -1;
            error = 10^-10;
            fdx = diff(f);
            [r,xi,iter] = NewtonR(f,fdx,xi,error);
            title(app.Grafica,['El valor de X es =' num2str(xi) ', iteraciones = ' num2str(iter)])
            plot(app.Grafica,b,y,'b',xi,r,'*r')
        end

        % Button pushed function: F4
        function F4ButtonPushed(app, event)
            syms x
            f = (x^4)+2*x-1;
            b = -2:0.1:4;
            y = double(subs(f,b));
            xi = 1;
            error = 10^-10;
            fdx = diff(f);
            [r,xi,iter] = NewtonR(f,fdx,xi,error);
            title(app.Grafica,['El valor de X es =' num2str(xi) ', iteraciones = ' num2str(iter)])
            plot(app.Grafica,b,y,'b',xi,r,'*r')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NewtonR = uilabel(app.UIFigure);
            app.NewtonR.Text = 'Método de Newton Raphson';
            app.F1 = uibutton(app.UIFigure, 'push');
            app.F1.ButtonPushedFcn = createCallbackFcn(app, @F1ButtonPushed, true);
            app.F1.Text = '(−1/10)x^2 + 3 ';
            app.F2 = uibutton(app.UIFigure, 'push');
            app.F2.ButtonPushedFcn = createCallbackFcn(app, @F2ButtonPushed, true);
            app.F2.Text = 'cos(𝑥) − 𝑥^3';
            app.F3 = uibutton(app.UIFigure, 'push');
            app.F3.ButtonPushedFcn = createCallbackFcn(app, @F3ButtonPushed, true);
            app.F3.Text = '𝑥^3 + 𝑥 + 16';
            app.F4 = uibutton(app.UIFigure, 'push');
            app.F4.ButtonPushedFcn = createCallbackFcn(app, @F4ButtonPushed, true);
            app.F4.Text = '𝑥^4 + 2𝑥 − 1';
            app.Grafica = uiaxes(app.UIFigure);
            title(app.Grafica, 'Title')
            xlabel(app.Grafica, 'X')
            ylabel(app.Grafica, 'Y')
            app.A00829022Label = uilabel(app.UIFigure);
            app.A00829022Label.Text = 'A00829022';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfazNewtonR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end