classdef polinomiosR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        VectordepolinomioLabel  matlab.ui.control.Label
        Vector                  matlab.ui.control.EditField
        RazLabel                matlab.ui.control.Label
        Raiz                    matlab.ui.control.EditField
        EncontrarRaiz           matlab.ui.control.Button
        Grafica                 matlab.ui.control.UIAxes
        GrficasButtonGroup      matlab.ui.container.ButtonGroup
        Grafica1                matlab.ui.control.RadioButton
        Grafica2                matlab.ui.control.RadioButton
        Grafica3                matlab.ui.control.RadioButton
        Grafica4                matlab.ui.control.RadioButton
        VerGrafica              matlab.ui.control.Button
        Limpiar                 matlab.ui.control.Button
        RacesdePolinomiosLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EncontrarRaiz
        function EncontrarRaizButtonPushed(app, event)
            A = textscan(app.Vector.Value,'%f','Delimiter',',');
            A = A{1};
            r = RaicesdePolinomios(A);
            r2 = r';
            app.Raiz.Value=num2str(r2); 
        end

        % Button pushed function: VerGrafica
        function VerGraficaButtonPushed(app, event)
           x = -10:0.1:10;
           if (app.Grafica1.Value)
               p1 = @(x) x.^3 -6*x.^2 +11*x -6;
               y = p1(x);
               plot(app.Grafica,x,y,'.r')
           elseif (app.Grafica2.Value)
               p1 = @(x) x.^3 - x.^2 - 4.*x + 4;
               y = p1(x);
               plot(app.Grafica,x,y,'.r')
           elseif (app.Grafica3.Value)
               p1 = @(x) -x.^4 + 10.*x.^2 - 9;
               y = p1(x);
               plot(app.Grafica,x,y,'.r')
           elseif (app.Grafica4.Value)
               p1 = @(x) -2.*x.^3 + x.^2 - 13.*x + 6;
               y = p1(x);
               plot(app.Grafica,x,y,'.r')
           end
        end

        % Button pushed function: Limpiar
        function LimpiarButtonPushed(app, event)
            app.Raiz.Value = '';
            app.Vector.Value = '';
            plot(app.Grafica,1,1,'-r')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.VectordepolinomioLabel = uilabel(app.UIFigure);
            app.VectordepolinomioLabel.Text = 'Vector de polinomio';
            app.Vector = uieditfield(app.UIFigure, 'text');
            app.RazLabel = uilabel(app.UIFigure);
            app.RazLabel.Text = 'Raíz:';
            app.Raiz = uieditfield(app.UIFigure, 'text');
            app.EncontrarRaiz = uibutton(app.UIFigure, 'push');
            app.EncontrarRaiz.ButtonPushedFcn = createCallbackFcn(app, @EncontrarRaizButtonPushed, true);
            app.EncontrarRaiz.Text = 'Encontrar raíz';
            app.Grafica = uiaxes(app.UIFigure);
            title(app.Grafica, 'Gráfica de polinomio')
            xlabel(app.Grafica, 'X')
            ylabel(app.Grafica, 'Y')
            app.GrficasButtonGroup = uibuttongroup(app.UIFigure);
            app.GrficasButtonGroup.Title = 'Gráficas:';
            app.Grafica1 = uiradiobutton(app.GrficasButtonGroup);
            app.Grafica1.Text = 'Gráfica 1';
            app.Grafica1.Value = true;
            app.Grafica2 = uiradiobutton(app.GrficasButtonGroup);
            app.Grafica2.Text = 'Gráfica 2';
            app.Grafica3 = uiradiobutton(app.GrficasButtonGroup);
            app.Grafica3.Text = 'Gráfica 3';
            app.Grafica4 = uiradiobutton(app.GrficasButtonGroup);
            app.Grafica4.Text = 'Gráfica 4';
            app.VerGrafica = uibutton(app.UIFigure, 'push');
            app.VerGrafica.ButtonPushedFcn = createCallbackFcn(app, @VerGraficaButtonPushed, true);
            app.VerGrafica.Text = 'Ver gráfica';
            app.Limpiar = uibutton(app.UIFigure, 'push');
            app.Limpiar.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.Limpiar.Text = 'Limpiar';
            app.RacesdePolinomiosLabel = uilabel(app.UIFigure);
            app.RacesdePolinomiosLabel.Text = 'Raíces de Polinomios';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = polinomiosR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end